classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        TeachButton     matlab.ui.control.Button
        ESTOPButton     matlab.ui.control.Button
        CocaColaButton  matlab.ui.control.Button
        FantaButton     matlab.ui.control.Button
        SoloButton      matlab.ui.control.Button
        LemonadeButton  matlab.ui.control.Button
        Image           matlab.ui.control.Image
    end

    
    properties (Access = public)
        Lemonade = false;
        Solo = false;
        Fanta = false;
        CocaCola = false;
        ESTOP = false;
        teach = false;
        
        Assignment;
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Assignment = Assignment2Group();
        end

        % Button pushed function: LemonadeButton
        function LemonadeButtonPushed(app, event)
            if app.Lemonade == true
                app.Lemonade = false;
            else
                app.Lemonade = true;
            end
            CollectOrderFromCustomer(Assignment2Group, "Lemonade")
        end

        % Button pushed function: SoloButton
        function SoloButtonPushed(app, event)
            if app.Solo == true
                app.Solo = false;
            else
                app.Solo = true;
            end
            CollectOrderFromCustomer(Assignment2Group, "Solo")
        end

        % Button pushed function: FantaButton
        function FantaButtonPushed(app, event)
            if app.Fanta == true
                app.Fanta = false;
            else
                app.Fanta = true;
            end
            CollectOrderFromCustomer(Assignment2Group, "Fanta")
        end

        % Button pushed function: CocaColaButton
        function CocaColaButtonPushed(app, event)
            if app.CocaCola == true
                app.CocaCola = false;
            else
                app.CocaCola = true;
            end
            CollectOrderFromCustomer(Assignment2Group, "CocaCola")
        end

        % Button pushed function: ESTOPButton
        function ESTOPButtonPushed(app, event)
            if app.ESTOP == true
                app.ESTOP = false;
            else
                app.ESTOP = true;
            end
        end

        % Button pushed function: TeachButton
        function TeachButtonPushed(app, event)
            if app.teach == true
                app.teach = false;
            else
                app.teach = true;
            end
            teachGUI();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Background.png');
            app.LemonadeButton = uibutton(app.UIFigure, 'push');
            app.LemonadeButton.ButtonPushedFcn = createCallbackFcn(app, @LemonadeButtonPushed, true);
            app.LemonadeButton.Text = 'Lemonade';
            app.SoloButton = uibutton(app.UIFigure, 'push');
            app.SoloButton.ButtonPushedFcn = createCallbackFcn(app, @SoloButtonPushed, true);
            app.SoloButton.Text = 'Solo';
            app.FantaButton = uibutton(app.UIFigure, 'push');
            app.FantaButton.ButtonPushedFcn = createCallbackFcn(app, @FantaButtonPushed, true);
            app.FantaButton.Text = 'Fanta';
            app.CocaColaButton = uibutton(app.UIFigure, 'push');
            app.CocaColaButton.ButtonPushedFcn = createCallbackFcn(app, @CocaColaButtonPushed, true);
            app.CocaColaButton.Text = 'Coca Cola';
            app.ESTOPButton = uibutton(app.UIFigure, 'push');
            app.ESTOPButton.ButtonPushedFcn = createCallbackFcn(app, @ESTOPButtonPushed, true);
            app.ESTOPButton.Text = 'E-STOP';
            app.TeachButton = uibutton(app.UIFigure, 'push');
            app.TeachButton.ButtonPushedFcn = createCallbackFcn(app, @TeachButtonPushed, true);
            app.TeachButton.Text = 'Teach';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end